classdef arrhythmia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        PlotButton                      matlab.ui.control.Button
        MinimumSliderLabel              matlab.ui.control.Label
        MinimumSlider                   matlab.ui.control.Slider
        MaximumSliderLabel              matlab.ui.control.Label
        MaximumSlider                   matlab.ui.control.Slider
        XVariableDropDownLabel          matlab.ui.control.Label
        XVariableDropDown               matlab.ui.control.DropDown
        YVariableDropDownLabel          matlab.ui.control.Label
        YVariableDropDown               matlab.ui.control.DropDown
        AgeLabel                        matlab.ui.control.Label
        ComponentLabel                  matlab.ui.control.Label
        YellowindicatesarrhythmiaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            load arrhythmia.mat
            
            % colormap winter
            %{
            if app.Button.Value == true
                tbl = X(X(:,1) < 20,:);
                x = tbl(:,6);
                y = tbl(:,7);
                c = tbl(:,2)
                scatter(app.UIAxes,x,y,[],c)
            elseif app.Button_2.Value == true
                tbl = X(X(:,1) < 40,:);
                tbl = tbl(tbl(:,1) >= 20,:);
                x = tbl(:,6);
                y = tbl(:,7);
                c = tbl(:,2)
                scatter(app.UIAxes,x,y,[],c)
            elseif app.Button_3.Value == true
                tbl = X(X(:,1) < 60,:);
                tbl = tbl(tbl(:,1) >= 40,:);
                x = tbl(:,6);
                y = tbl(:,7);
                c = tbl(:,2)
                scatter(app.UIAxes,x,y,[],c)
            elseif app.Button_4.Value == true
                tbl = X(X(:,1) >= 60,:);
                x = tbl(:,6);
                y = tbl(:,7);
                c = tbl(:,2)
                scatter(app.UIAxes,x,y,[],c)
            %}
            
            % Limit table by ages
            v1 = app.MinimumSlider.Value;
            v2 = app.MaximumSlider.Value;
            tbl = X(X(:,1) >= v1,:);
            tbl = tbl(tbl(:,1) <= v2,:);
            
            xname = string(app.XVariableDropDown.Value);
            yname = string(app.YVariableDropDown.Value);
            if xname == "QRS Duration"
                x = tbl(:,5);
            elseif xname == "P-R Interval"
                x = tbl(:,6);
            elseif xname == "Q-T Interval"
                x = tbl(:,7);
            elseif xname == "T Interval"
                x = tbl(:,8);
            else
                x = tbl(:,9);
            end
            if yname == "QRS Duration"
                y = tbl(:,5);
            elseif yname == "P-R Interval"
                y = tbl(:,6);
            elseif yname == "Q-T Interval"
                y = tbl(:,7);
            elseif yname == "T Interval"
                y = tbl(:,8);
            else
                y = tbl(:,9);
            end
            
            tbly = X(:,1) <= v2 & X(:,1) >= v1;
            tbly = Y(tbly == 1);
            c = tbly(:,1);
            cTF = c ~= 1;
            
            scatter(app.UIAxes,x,y,[],cTF,'filled')            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cardiac Patterns by Age')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MinimumSliderLabel = uilabel(app.UIFigure);
            app.MinimumSliderLabel.Text = 'Minimum';
            app.MinimumSlider = uislider(app.UIFigure);
            app.MaximumSliderLabel = uilabel(app.UIFigure);
            app.MaximumSliderLabel.Text = 'Maximum';
            app.MaximumSlider = uislider(app.UIFigure);
            app.XVariableDropDownLabel = uilabel(app.UIFigure);
            app.XVariableDropDownLabel.Text = 'X-Variable';
            app.XVariableDropDown = uidropdown(app.UIFigure);
            app.XVariableDropDown.Items = {'QRS Duration', 'P-R Interval', 'Q-T Interval', 'T Interval', 'P Interval'};
            app.XVariableDropDown.Value = 'QRS Duration';
            app.YVariableDropDownLabel = uilabel(app.UIFigure);
            app.YVariableDropDownLabel.Text = 'Y-Variable';
            app.YVariableDropDown = uidropdown(app.UIFigure);
            app.YVariableDropDown.Items = {'QRS Duration', 'P-R Interval', 'Q-T Interval', 'T Interval', 'P Interval'};
            app.YVariableDropDown.Value = 'QRS Duration';
            app.AgeLabel = uilabel(app.UIFigure);
            app.AgeLabel.Text = 'Age';
            app.ComponentLabel = uilabel(app.UIFigure);
            app.ComponentLabel.Text = 'Component';
            app.YellowindicatesarrhythmiaLabel = uilabel(app.UIFigure);
            app.YellowindicatesarrhythmiaLabel.Text = 'Yellow indicates arrhythmia.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arrhythmia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end